classdef OSMAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        FileMenu        matlab.ui.container.Menu
        NewMenu         matlab.ui.container.Menu
        OpenMenu        matlab.ui.container.Menu
        SaveMenu        matlab.ui.container.Menu
        SaveAsMenu      matlab.ui.container.Menu
        CloseMenu       matlab.ui.container.Menu
        ExitMenu        matlab.ui.container.Menu
        EditMenu        matlab.ui.container.Menu
        HelpMenu        matlab.ui.container.Menu
        AboutMenu       matlab.ui.container.Menu
        VideoConverter  matlab.ui.container.Panel
        osmClassic2D    matlab.ui.container.Panel
        osmClassic3D    matlab.ui.container.Panel
        osmLive2D       matlab.ui.container.Panel
        osmLive3D       matlab.ui.container.Panel
        VideoRecorder   matlab.ui.container.Panel
        MainMenu        matlab.ui.container.Panel
    end

    
    methods (Access = private)
        
        function hideAllPanels(app)
            app.MainMenu.Visible = 'off';
            app.VideoConverter.Visible = 'off';
            app.VideoRecorder.Visible = 'off';
            app.osmClassic2D.Visible = 'off';
            app.osmClassic3D.Visible = 'off';
            app.osmLive2D.Visible = 'off';
            app.osmLive3D.Visible = 'off';
        end
        
        function moveAllPanels(app)
            tmpPosition = app.UIFigure.Postion;
            app.MainMenu.Position = tmpPosition;
            app.VideoConverter.Position = tmpPosition;
            app.VideoRecorder.Position = tmpPosition;
            app.osmClassic2D.Position = tmpPosition;
            app.osmClassic3D.Position = tmpPosition;
            app.osmLive2D.Position = tmpPosition;
            app.osmLive3D.Position = tmpPosition;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hideAllPanels;
            app.moveAllPanels;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.Text = 'Close';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.VideoConverter = uipanel(app.UIFigure);
            app.osmClassic2D = uipanel(app.UIFigure);
            app.osmClassic3D = uipanel(app.UIFigure);
            app.osmLive2D = uipanel(app.UIFigure);
            app.osmLive3D = uipanel(app.UIFigure);
            app.VideoRecorder = uipanel(app.UIFigure);
            app.MainMenu = uipanel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSMAPP

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end