classdef OSMAPP_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewMenu                 matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        SaveAsMenu              matlab.ui.container.Menu
        CloseMenu               matlab.ui.container.Menu
        ExitMenu                matlab.ui.container.Menu
        EditMenu                matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        AboutMenu               matlab.ui.container.Menu
        VideoConverter          matlab.ui.container.Panel
        osmClassic              matlab.ui.container.Panel
        STARTButton             matlab.ui.control.Button
        osmClassic2D            matlab.ui.container.Panel
        osmLive                 matlab.ui.container.Panel
        osmLive2D               matlab.ui.container.Panel
        VideoRecorder           matlab.ui.container.Panel
        MainMenu                matlab.ui.container.Panel
        GridLayout              matlab.ui.container.GridLayout
        OSMSOFTWARESUITELabel   matlab.ui.control.Label
        VideoProgramsPanel      matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        VideoConverterButton    matlab.ui.control.Button
        VideoRecorderButton     matlab.ui.control.Button
        VideoExtensometryPanel  matlab.ui.container.Panel
        GridLayout3             matlab.ui.container.GridLayout
        OSMClassic1DButton      matlab.ui.control.Button
        OSMClassic2DButton      matlab.ui.control.Button
        LiveExtensometryPanel   matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        OSMLive1DButton         matlab.ui.control.Button
        OSMLive2DButton         matlab.ui.control.Button
        ByDanielRAldrichLabel   matlab.ui.control.Label
        imageContainer          matlab.ui.container.Panel
        imageViewer             matlab.ui.control.UIAxes
        intensityContainer      matlab.ui.container.Panel
        intensityViewer         matlab.ui.control.UIAxes
        imageManipulation       matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        invertColor             matlab.ui.control.CheckBox
        rotateCCW               matlab.ui.control.Button
        rotateCW                matlab.ui.control.Button
        ResetROIButton          matlab.ui.control.Button
        UpperLimitButton        matlab.ui.control.Button
        LowerLimitButton        matlab.ui.control.Button
        ResetLimitsButton       matlab.ui.control.Button
        LeftButton              matlab.ui.control.Button
        AutoButton              matlab.ui.control.Button
        RightButton             matlab.ui.control.Button
    end

    
    properties (Access = public)
        ROI             % Region of Interest
        ROTATION = 0;   % Image Rotation
        IMAGE           % Image Object
        RAWIMAGE        % Raw Image Data
        PROIMAGE        % Processed Image Data
        INTENSITY       % Intensity Plot
        upperIBObj = -1 % Upper Bound Objects
        lowerIBObj = -1 % Lower Bound Objects
        IBounds = [0,1] % Lower and Upper Bounds
        IData = [];
        LBound = [];    % Left Guess
        RBound = [];    % Right Guess
        IMGDIR          % Image Directory
        imageIdx = 0;   % Current Image/Frame Number
        totalImg = 0;   % Total Number of Images/Frames
        isVideo = -1;   % Video Flag
        imageList = {}; % Image List
        videoObj        % Video Object
    end
    
    properties (Access = private)
        RESDIR = fullfile(cd,'res') % Resource Directory
    end
    
    methods (Access = public)
        
        function hideAllPanels(app)
            app.MainMenu.Visible = 'off';
            app.VideoConverter.Visible = 'off';
            app.VideoRecorder.Visible = 'off';
            app.osmClassic.Visible = 'off';
            app.osmClassic2D.Visible = 'off';
            app.osmLive.Visible = 'off';
            app.osmLive2D.Visible = 'off';
            app.imageContainer.Visible = 'off';
            app.intensityContainer.Visible = 'off';
            app.imageManipulation.Visible = 'off';
        end
        
        function moveAllPanels(app)
            % Moves all the panels to the 
            tmpPosition = app.UIFigure.Position;
            app.MainMenu.Position = [1,1,tmpPosition(3:4)];
            app.VideoConverter.Position = [1,1,tmpPosition(3:4)];
            app.VideoRecorder.Position = [1,1,tmpPosition(3:4)];
            app.osmClassic.Position = [1,1,tmpPosition(3:4)];
            app.osmClassic2D.Position = [1,1,tmpPosition(3:4)];
            app.osmLive.Position = [1,1,tmpPosition(3:4)];
            app.osmLive2D.Position = [1,1,tmpPosition(3:4)];
        end
        
        function resetVariables(app)
            app.totalImg  =  0;
            app.imageIdx  =  0;
            app.isVideo   = -1;
            app.imageList = {};
            app.videoObj  = [];
            app.ROTATION  =  0;
            app.invertColor.Value = false;
            app.RAWIMAGE = zeros(100);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            if ispc
                % Windows is not case-sensitive
                onPath = contains(lower(path),lower(app.RESDIR));
            else
                onPath = contains(path,app.RESDIR);
            end
            
            if ~onPath
                addpath("res\")
                delete(app);
                OSMAPP;
                return;
            end
            
            app.moveAllPanels;
            
            bin.setupViewers(app);
            
            app.openMainMenu;
        end

        % Button pushed function: OSMClassic1DButton
        function tmpTestFunc(app, event)
            % TEMPORARY IMAGE TEST

            bin.loadFileFolder(app)
            app.NewMenu.Enable = 'on';
            app.CloseMenu.Enable = 'on';
            app.hideAllPanels;
            app.osmClassic.Visible = 'on';
            app.imageContainer.Visible = 'on';
            app.intensityContainer.Visible = 'on';
            app.imageManipulation.Visible = 'on';
        end

        % Menu selected function: CloseMenu
        function openMainMenu(app, event)
            app.hideAllPanels;
            app.resetVariables;
            bin.updateImage(app,-1);
            app.MainMenu.Visible = 'on';
            app.NewMenu.Enable = 'off';
            app.CloseMenu.Enable = 'off';
        end

        % Size changed function: UIFigure, intensityContainer
        function UIFigureSizeChanged(app, event)
            app.moveAllPanels;            
        end

        % Callback function: ExitMenu, UIFigure
        function UIFigureCloseRequest(app, event)
            switch uiconfirm(app.UIFigure,...
                    'Are You Sure You Would Like to Close the Program?',...
                    'Confirm Close',...
                    'Icon','warning')
                case 'OK'
                    delete(app)
                otherwise
                    % Do not close the application
            end
        end

        % Value changed function: invertColor
        function invertImage(app, event)
            bin.updateImage(app);
        end

        % Button pushed function: rotateCCW, rotateCW
        function rotateImage(app, event)
            switch event.Source.Tag
                case '+1'
                    app.ROTATION = mod(app.ROTATION+1,4);
                    rotateFlag = 1;
                case '-1'
                    app.ROTATION = mod(app.ROTATION-1,4);
                    rotateFlag = 2;
            end
            bin.updateImage(app,rotateFlag)
        end

        % Button pushed function: ResetROIButton
        function resetROI(app, event)
            bin.updateImage(app,-1)
        end

        % Menu selected function: NewMenu
        function newMenuCallback(app, event)
            if app.osmClassic.Visible
                bin.loadFileFolder(app)
            elseif app.osmClassic2D.Visible
                
            elseif app.osmLive.Visible
                
            elseif app.osmLive2D.Visible
                
            end
        end

        % Button pushed function: LowerLimitButton, UpperLimitButton
        function setIntensityLimit(app, event)
            tmp = drawcrosshair(app.intensityViewer);
            newBound = tmp.Position(2)/size(app.PROIMAGE,1);
            delete(tmp);
            switch event.Source.Tag
                case "Upper"
                    if newBound > app.IBounds(1)
                        app.IBounds(2) = newBound;
                    end
                case "Lower"
                    if newBound < app.IBounds(2)
                        app.IBounds(1) = newBound;
                    end
            end
            bin.updateIBounds(app);
        end

        % Button pushed function: ResetLimitsButton
        function resetIntensityLimit(app, event)
            app.IBounds = [0,1];
            bin.updateIBounds(app);
        end

        % Button pushed function: LeftButton, RightButton
        function setLRGuesses(app, event)
            tmp = drawcrosshair(app.intensityViewer);
            newGuess = tmp.Position(1);
            delete(tmp);
            switch event.Source.Tag
                case "Left"
                    if newBound > app.RBound.Value
                        app.LBound.Value = newGuess;
                    end
                case "Right"
                    if newBound < app.LBound.Value
                        app.RBound.Value= newGuess;
                    end
            end
        end

        % Button pushed function: AutoButton
        function autoLRGuesses(app, event)
            app.LBound.Value = ...
                find(app.INTENSITY.YData>0.9*max(app.INTENSITY.YData),1,'first');
            app.RBound.Value = ...
                find(app.INTENSITY.YData>0.9*max(app.INTENSITY.YData),1,'last');
        end

        % Button pushed function: STARTButton
        function classicStart(app, event)
            Classic.processImages(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @newMenuCallback, true);
            app.NewMenu.Enable = 'off';
            app.NewMenu.Accelerator = 'n';
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Visible = 'off';
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open...';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Enable = 'off';
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.Enable = 'off';
            app.SaveAsMenu.Text = 'Save As...';
            app.CloseMenu = uimenu(app.FileMenu);
            app.CloseMenu.MenuSelectedFcn = createCallbackFcn(app, @openMainMenu, true);
            app.CloseMenu.Text = 'Close';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ExitMenu.Separator = 'on';
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.Text = 'About';
            app.VideoConverter = uipanel(app.UIFigure);
            app.osmClassic = uipanel(app.UIFigure);
            app.STARTButton = uibutton(app.osmClassic, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @classicStart, true);
            app.STARTButton.Text = 'START';
            app.osmClassic2D = uipanel(app.UIFigure);
            app.osmLive = uipanel(app.UIFigure);
            app.osmLive2D = uipanel(app.UIFigure);
            app.VideoRecorder = uipanel(app.UIFigure);
            app.MainMenu = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.MainMenu);
            app.OSMSOFTWARESUITELabel = uilabel(app.GridLayout);
            app.OSMSOFTWARESUITELabel.Text = 'OSM SOFTWARE SUITE';
            app.VideoProgramsPanel = uipanel(app.GridLayout);
            app.VideoProgramsPanel.Title = 'Video Programs';
            app.GridLayout2 = uigridlayout(app.VideoProgramsPanel);
            app.VideoConverterButton = uibutton(app.GridLayout2, 'push');
            app.VideoConverterButton.Icon = 'cutVideo.png';
            app.VideoConverterButton.Enable = 'off';
            app.VideoConverterButton.Text = 'Video Converter';
            app.VideoRecorderButton = uibutton(app.GridLayout2, 'push');
            app.VideoRecorderButton.Icon = 'recVideo.png';
            app.VideoRecorderButton.Enable = 'off';
            app.VideoRecorderButton.Text = 'Video Recorder';
            app.VideoExtensometryPanel = uipanel(app.GridLayout);
            app.VideoExtensometryPanel.Title = 'Video Extensometry';
            app.GridLayout3 = uigridlayout(app.VideoExtensometryPanel);
            app.OSMClassic1DButton = uibutton(app.GridLayout3, 'push');
            app.OSMClassic1DButton.ButtonPushedFcn = createCallbackFcn(app, @tmpTestFunc, true);
            app.OSMClassic1DButton.Icon = 'imageLines.png';
            app.OSMClassic1DButton.Text = 'OSM-Classic (1D)';
            app.OSMClassic2DButton = uibutton(app.GridLayout3, 'push');
            app.OSMClassic2DButton.Icon = 'imageDots.png';
            app.OSMClassic2DButton.Enable = 'off';
            app.OSMClassic2DButton.Text = 'OSM-Classic (2D)';
            app.LiveExtensometryPanel = uipanel(app.GridLayout);
            app.LiveExtensometryPanel.Title = 'Live Extensometry';
            app.GridLayout4 = uigridlayout(app.LiveExtensometryPanel);
            app.OSMLive1DButton = uibutton(app.GridLayout4, 'push');
            app.OSMLive1DButton.Icon = 'imageLinesLive.png';
            app.OSMLive1DButton.Enable = 'off';
            app.OSMLive1DButton.Text = 'OSM-Live (1D)';
            app.OSMLive2DButton = uibutton(app.GridLayout4, 'push');
            app.OSMLive2DButton.Icon = 'imageDotsLive.png';
            app.OSMLive2DButton.Enable = 'off';
            app.OSMLive2DButton.Text = 'OSM-Live (2D)';
            app.ByDanielRAldrichLabel = uilabel(app.GridLayout);
            app.ByDanielRAldrichLabel.Text = 'By: Daniel R. Aldrich';
            app.imageContainer = uipanel(app.UIFigure);
            app.imageViewer = uiaxes(app.imageContainer);
            app.imageViewer.XTick = [];
            app.imageViewer.YTick = [];
            app.imageViewer.YTickLabel = '';
            app.imageViewer.ZTick = [];
            app.intensityContainer = uipanel(app.UIFigure);
            app.intensityContainer.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.intensityViewer = uiaxes(app.intensityContainer);
            app.intensityViewer.XTick = [];
            app.intensityViewer.YTick = [];
            app.intensityViewer.YTickLabel = '';
            app.intensityViewer.ZTick = [];
            app.imageManipulation = uipanel(app.UIFigure);
            app.imageManipulation.Title = 'Image Manipulation';
            app.GridLayout5 = uigridlayout(app.imageManipulation);
            app.invertColor = uicheckbox(app.GridLayout5);
            app.invertColor.ValueChangedFcn = createCallbackFcn(app, @invertImage, true);
            app.invertColor.Text = 'Invert Image';
            app.rotateCCW = uibutton(app.GridLayout5, 'push');
            app.rotateCCW.ButtonPushedFcn = createCallbackFcn(app, @rotateImage, true);
            app.rotateCCW.Tag = '+1';
            app.rotateCCW.Text = 'Rotate -';
            app.rotateCW = uibutton(app.GridLayout5, 'push');
            app.rotateCW.ButtonPushedFcn = createCallbackFcn(app, @rotateImage, true);
            app.rotateCW.Tag = '-1';
            app.rotateCW.Text = 'Rotate +';
            app.ResetROIButton = uibutton(app.GridLayout5, 'push');
            app.ResetROIButton.ButtonPushedFcn = createCallbackFcn(app, @resetROI, true);
            app.ResetROIButton.Text = 'Reset ROI';
            app.UpperLimitButton = uibutton(app.GridLayout5, 'push');
            app.UpperLimitButton.ButtonPushedFcn = createCallbackFcn(app, @setIntensityLimit, true);
            app.UpperLimitButton.Tag = 'Upper';
            app.UpperLimitButton.Text = 'Upper Limit';
            app.LowerLimitButton = uibutton(app.GridLayout5, 'push');
            app.LowerLimitButton.ButtonPushedFcn = createCallbackFcn(app, @setIntensityLimit, true);
            app.LowerLimitButton.Tag = 'Lower';
            app.LowerLimitButton.Text = 'Lower Limit';
            app.ResetLimitsButton = uibutton(app.GridLayout5, 'push');
            app.ResetLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @resetIntensityLimit, true);
            app.ResetLimitsButton.Text = {'Reset'; 'Limits'};
            app.LeftButton = uibutton(app.GridLayout5, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @setLRGuesses, true);
            app.LeftButton.Tag = 'Left';
            app.LeftButton.Text = {'Left'; ''};
            app.AutoButton = uibutton(app.GridLayout5, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @autoLRGuesses, true);
            app.AutoButton.Text = {'Auto'; ''};
            app.RightButton = uibutton(app.GridLayout5, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @setLRGuesses, true);
            app.RightButton.Tag = 'Right';
            app.RightButton.Text = {'Right'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OSMAPP_2

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end